classdef SBTimer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Timer                        matlab.ui.Figure
        Tabs                         matlab.ui.container.TabGroup
        Create                       matlab.ui.container.Tab
        Selecter                     matlab.ui.control.Button
        CurrentFolderEditFieldLabel  matlab.ui.control.Label
        CurrentFolder                matlab.ui.control.EditField
        Explanation                  matlab.ui.control.Label
        SequenceNameEditFieldLabel   matlab.ui.control.Label
        SeqName                      matlab.ui.control.EditField
        TotalBuildersEditFieldLabel  matlab.ui.control.Label
        TotalBuilders                matlab.ui.control.NumericEditField
        EditBuilderLabel             matlab.ui.control.Label
        SelectBuilder                matlab.ui.control.DropDown
        EditBuilderPanel             matlab.ui.container.Panel
        BuilderNameEditFieldLabel    matlab.ui.control.Label
        BuilderNameEdit              matlab.ui.control.EditField
        SplitsTable                  matlab.ui.control.Table
        AddAbove                     matlab.ui.control.Button
        AddBelow                     matlab.ui.control.Button
        Delete                       matlab.ui.control.Button
        AllTimersTab                 matlab.ui.container.Tab
        AllTimers                    matlab.ui.control.Table
        SAVEButton                   matlab.ui.control.Button
        SplitsTab                    matlab.ui.container.Tab
    end


    properties (Access = private)
        readmeLocal % readme file location
        readmeFID %FID for readme
        seqName
        createBuilderNum % what num is active builder?
    end

    properties (Access = public)
        Splits = cell(1,4); % Cell of cells of splits to do 
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.BuilderNameEdit.Value = app.SelectBuilder.Value; %assignment
        end

        % Button pushed function: Selecter
        function SelecterButtonPushed(app, event)
            [f,p] = uiputfile('README.md'); %get file location
            app.readmeLocal = [p f]; %concat
            app.readmeFID = fopen(app.readmeLocal, 'w'); %w/ writing
            parts = strsplit(p); %for folder name
            app.CurrentFolder.Value = parts(end); %bottom folder
            
        end

        % Close request function: Timer
        function TimerCloseRequest(app, event)
            fclose all; %be safe fam
            delete(app)
            
        end

        % Value changed function: SeqName
        function SeqNameValueChanged(app, event)
            app.seqName = app.SeqName.Value;% save this for later
        end

        % Value changed function: TotalBuilders
        function TotalBuildersValueChanged(app, event)
            value = app.TotalBuilders.Value;
            opts = app.SelectBuilder.Items;
            existing = numel(opts); %how many already things
            if(existing > value) %more than total builders
                opts = opts(1:value);
                app.Splits = app.Splits(1:value); %manage data
            elseif(existing < value) %need to add more things
                for indOpts = existing:value
                    opts{indOpts} = sprintf('Builder %d',indOpts);
                    app.Splits{indOpts} = cell(1);
                end %for
            end
            app.SelectBuilder.Items = opts; %put that back in
        end

        % Value changed function: SelectBuilder
        function SelectBuilderValueChanged(app, event)
            name = app.SelectBuilder.Value;
            app.BuilderNameEdit.Value = name; %add to name
            app.createBuilderNum = find(contains(app.SelectBuilder.Items, name));
            app.SplitsTable.Data = app.Splits{app.createBuilderNum};
        end

        % Value changed function: BuilderNameEdit
        function BuilderNameEditValueChanged(app, event)
            newName = app.BuilderNameEdit.Value;
            app.SelectBuilder.Items{app.createBuilderNum} = newName;
            app.SelectBuilder.Value = newName;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Timer = uifigure;
            app.Timer.Name = 'UI Figure';
            app.Timer.CloseRequestFcn = createCallbackFcn(app, @TimerCloseRequest, true);
            app.Tabs = uitabgroup(app.Timer);
            app.Create = uitab(app.Tabs);
            app.Create.Title = 'Create Timers';
            app.Selecter = uibutton(app.Create, 'push');
            app.Selecter.ButtonPushedFcn = createCallbackFcn(app, @SelecterButtonPushed, true);
            app.Selecter.Text = 'Select README';
            app.CurrentFolderEditFieldLabel = uilabel(app.Create);
            app.CurrentFolderEditFieldLabel.Text = 'Current Folder';
            app.CurrentFolder = uieditfield(app.Create, 'text');
            app.CurrentFolder.Editable = 'off';
            app.Explanation = uilabel(app.Create);
            app.Explanation.Text = {'Please create a README file within the folder'; 'for your sequence.  Create one folder per '; 'sequence, with the title ''README.md'' as the '; 'filename.  Name your folder for the sequence.'};
            app.SequenceNameEditFieldLabel = uilabel(app.Create);
            app.SequenceNameEditFieldLabel.Text = 'Sequence Name';
            app.SeqName = uieditfield(app.Create, 'text');
            app.SeqName.ValueChangedFcn = createCallbackFcn(app, @SeqNameValueChanged, true);
            app.TotalBuildersEditFieldLabel = uilabel(app.Create);
            app.TotalBuildersEditFieldLabel.Text = 'Total Builders';
            app.TotalBuilders = uieditfield(app.Create, 'numeric');
            app.TotalBuilders.Limits = [0 Inf];
            app.TotalBuilders.ValueDisplayFormat = '%.0f';
            app.TotalBuilders.ValueChangedFcn = createCallbackFcn(app, @TotalBuildersValueChanged, true);
            app.TotalBuilders.Value = 4;
            app.EditBuilderLabel = uilabel(app.Create);
            app.EditBuilderLabel.Text = 'Edit Builder';
            app.SelectBuilder = uidropdown(app.Create);
            app.SelectBuilder.Items = {'Builder 1', 'Builder 2', 'Builder 3', 'Builder 4'};
            app.SelectBuilder.Editable = 'on';
            app.SelectBuilder.ValueChangedFcn = createCallbackFcn(app, @SelectBuilderValueChanged, true);
            app.SelectBuilder.Value = 'Builder 1';
            app.EditBuilderPanel = uipanel(app.Create);
            app.EditBuilderPanel.Title = 'Edit Builder';
            app.BuilderNameEditFieldLabel = uilabel(app.EditBuilderPanel);
            app.BuilderNameEditFieldLabel.Text = 'Builder Name';
            app.BuilderNameEdit = uieditfield(app.EditBuilderPanel, 'text');
            app.BuilderNameEdit.ValueChangedFcn = createCallbackFcn(app, @BuilderNameEditValueChanged, true);
            app.SplitsTable = uitable(app.EditBuilderPanel);
            app.SplitsTable.ColumnName = {'Splits'};
            app.SplitsTable.RowName = {};
            app.AddAbove = uibutton(app.EditBuilderPanel, 'push');
            app.AddAbove.Text = 'Add Split Above';
            app.AddBelow = uibutton(app.EditBuilderPanel, 'push');
            app.AddBelow.Text = 'Add Split Below';
            app.Delete = uibutton(app.EditBuilderPanel, 'push');
            app.Delete.Text = 'Delete Split';
            app.AllTimersTab = uitab(app.Tabs);
            app.AllTimersTab.Title = 'All Timers';
            app.AllTimers = uitable(app.AllTimersTab);
            app.AllTimers.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.AllTimers.RowName = {};
            app.SAVEButton = uibutton(app.AllTimersTab, 'push');
            app.SAVEButton.Text = 'SAVE!';
            app.SplitsTab = uitab(app.Tabs);
            app.SplitsTab.Title = 'Splits';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SBTimer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Timer)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Timer)
        end
    end
end